classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        HeartPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        AccPanel                matlab.ui.container.Panel
        UIAxes2                 matlab.ui.control.UIAxes
        BmiPanel                matlab.ui.container.Panel
        Panel_2                 matlab.ui.container.Panel
        TextArea_2              matlab.ui.control.TextArea
        TextArea                matlab.ui.control.TextArea
        YourBMILabel            matlab.ui.control.Label
        CalculateButton         matlab.ui.control.Button
        WeightkgEditField       matlab.ui.control.NumericEditField
        WeightkgEditFieldLabel  matlab.ui.control.Label
        HeightcmEditField       matlab.ui.control.NumericEditField
        HeightcmEditFieldLabel  matlab.ui.control.Label
        ButtonGroup             matlab.ui.container.ButtonGroup
        HeartRateMonitorButton  matlab.ui.control.ToggleButton
        BMICalculatorButton     matlab.ui.control.ToggleButton
        AccelerometerButton     matlab.ui.control.ToggleButton
        FitnessTrackerAppLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        StopPlot = false;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
                selectedButton = app.ButtonGroup.SelectedObject;
            
                app.StopPlot = true;
                pause(0.1);  % Give time for any loop to exit

            
                if selectedButton == app.BMICalculatorButton
                    app.BmiPanel.Visible = true;
                    app.HeartPanel.Visible = false;
                    app.AccPanel.Visible = false;
                    
            
                elseif selectedButton == app.HeartRateMonitorButton
                    app.HeartPanel.Visible = true;
                    app.BmiPanel.Visible = false;
                    app.AccPanel.Visible = false;

                     app.StopPlot = false;

        
                      x = [];
                    y = [];
                    h = plot(app.UIAxes, NaN, NaN);  
                    app.UIAxes.XLim = [0 10];
                    app.UIAxes.YLim = [-1.2 1.2];
            
                    for t = 0:0.1:100
                        if app.StopPlot
                            break;
                        end

            
                        x(end+1) = mod(t, 10);
                        y(end+1) = sin(3*t);
            
                        set(h, 'XData', x, 'YData', y);
                        drawnow
                        pause(0.05);
            
                        if length(x) > 100
                            x(1) = [];
                            y(1) = [];
                        end
                    end
                else
                    app.AccPanel.Visible = true;
                    app.BmiPanel.Visible = false;
                    app.HeartPanel.Visible = false;

                    
                end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
             height_cm = app.HeightcmEditField.Value; 
            weight_kg = app.WeightkgEditField.Value; 
    
    if isnan(height_cm) || isnan(weight_kg) || height_cm <= 0 || weight_kg <= 0
        app.TextArea.Value = 'Please enter valid positive values for height and weight.';
        return;
    end
        height_m = height_cm / 100; 
        bmi = weight_kg / (height_m^2); 
    
       app.TextArea.Value = sprintf("%.2f",bmi);
       if bmi < 18.5
           app.TextArea_2.Value = "UNDERWEIGHT";
       elseif bmi >= 18.5 & bmi <= 24.9
           app.TextArea_2.Value = "NORMAL";
       elseif bmi >= 25 & bmi <= 29.9
           app.TextArea_2.Value = "OVERWEIGHT";
       elseif bmi >= 30
           app.TextArea_2.Value = "OBESE";
       end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FitnessTrackerAppLabel = uilabel(app.UIFigure);
            app.FitnessTrackerAppLabel.Text = 'Fitness Tracker App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.AccelerometerButton = uitogglebutton(app.ButtonGroup);
            app.AccelerometerButton.Text = 'Accelerometer';
            app.AccelerometerButton.Value = true;
            app.BMICalculatorButton = uitogglebutton(app.ButtonGroup);
            app.BMICalculatorButton.Text = 'BMI Calculator';
            app.HeartRateMonitorButton = uitogglebutton(app.ButtonGroup);
            app.HeartRateMonitorButton.Text = 'Heart Rate Monitor';
            app.BmiPanel = uipanel(app.UIFigure);
            app.BmiPanel.Visible = 'off';
            app.HeightcmEditFieldLabel = uilabel(app.BmiPanel);
            app.HeightcmEditFieldLabel.Text = 'Height (cm)';
            app.HeightcmEditField = uieditfield(app.BmiPanel, 'numeric');
            app.WeightkgEditFieldLabel = uilabel(app.BmiPanel);
            app.WeightkgEditFieldLabel.Text = 'Weight (kg)';
            app.WeightkgEditField = uieditfield(app.BmiPanel, 'numeric');
            app.CalculateButton = uibutton(app.BmiPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Panel_2 = uipanel(app.BmiPanel);
            app.YourBMILabel = uilabel(app.Panel_2);
            app.YourBMILabel.Text = 'Your BMI: ';
            app.TextArea = uitextarea(app.Panel_2);
            app.TextArea_2 = uitextarea(app.Panel_2);
            app.AccPanel = uipanel(app.UIFigure);
            app.UIAxes2 = uiaxes(app.AccPanel);
            title(app.UIAxes2, 'Accelerometer')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.HeartPanel = uipanel(app.UIFigure);
            app.HeartPanel.Visible = 'off';
            app.UIAxes = uiaxes(app.HeartPanel);
            title(app.UIAxes, 'Heart Rate')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end